classdef tvc_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PlotsPanel                      matlab.ui.container.Panel
        flight                          matlab.ui.control.UIAxes
        theta_x                         matlab.ui.control.UIAxes
        theta_y                         matlab.ui.control.UIAxes
        altitude                        matlab.ui.control.UIAxes
        model                           matlab.ui.control.UIAxes
        DataReadoutsPanel               matlab.ui.container.Panel
        SpeedmsLabel                    matlab.ui.control.Label
        speed                           matlab.ui.control.TextArea
        Accelzaxisms2Label              matlab.ui.control.Label
        acceleration                    matlab.ui.control.TextArea
        AltitudemLabel                  matlab.ui.control.Label
        altitude_readout                matlab.ui.control.TextArea
        RocketOrientationxaxisdegLabel  matlab.ui.control.Label
        theta_x_readout                 matlab.ui.control.TextArea
        RocketOrientationyaxisdegLabel  matlab.ui.control.Label
        theta_y_readout                 matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        InitialStatesTab                matlab.ui.container.Tab
        InitialRocketAnglexaxisSpinnerLabel  matlab.ui.control.Label
        theta_r0x                       matlab.ui.control.Spinner
        InitialRocketAngleyaxisLabel    matlab.ui.control.Label
        theta_r0y                       matlab.ui.control.Spinner
        DisturbancesTab                 matlab.ui.container.Tab
        DisturbanceForcexaxisLabel      matlab.ui.control.Label
        d_x                             matlab.ui.control.Spinner
        DisturbanceForceyaxisLabel      matlab.ui.control.Label
        d_y                             matlab.ui.control.Spinner
        ApplyDisturbancesButton         matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        running = true;
        disturbances = [0 0];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.running = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Initial parameters
            dt = 0.01;
            p0 = [0 0 0];
            v0 = [0 0 0];
            theta_g0 = [0 0];
            theta_r0 = [app.theta_r0x.Value app.theta_r0y.Value];
            prev_error = 0;
            error_i = 0;
            omega_0 = [0 0];
            t = 0;
            
            % Clear all figures before starting
            app.flight.cla;
            app.theta_x.cla;
            app.theta_y.cla;
            app.altitude.cla;
            app.model.cla;
                        
            % Create animatedline objects for live plotting
            alt = animatedline(app.altitude, 'Color', 'r');
            theta_rx = animatedline(app.theta_x, 'Color', 'r');
            theta_gx = animatedline(app.theta_x, 'Color', 'b');
            theta_xref = animatedline(app.theta_x, 'Color', 'k', 'LineStyle', '--');
            theta_ry = animatedline(app.theta_y, 'Color', 'r');
            theta_gy = animatedline(app.theta_y, 'Color', 'b');
            theta_yref = animatedline(app.theta_y, 'Color', 'k', 'LineStyle', '--');
            plot3(app.flight, p0(1), p0(2), p0(2), 'r')
            grid(app.flight, 'on')
            hold(app.flight, 'on')
            zlabel(app.flight, 'z [m]')
            flightpath = animatedline(app.flight, 'Color', 'r');
            max_lim_path = max([p0(1) p0(2)]);
            max_lim_x = max(abs([theta_r0(1) theta_g0(1)]));
            max_lim_y = max(abs([theta_r0(2) theta_g0(2)]));
            
            % Legends
            legend(app.theta_x, {'\theta_r', '\theta_g'})
            legend(app.theta_y, {'\theta_r', '\theta_g'})
            
            % 3D Model Animation
            patchData = stlread('falcon9.stl');
            p1 = patch(app.model, patchData, 'EdgeColor', 'none', 'FaceColor', '[0.8 0.8 1.0]');
            OrigVerts = p1.Vertices;
            
            daspect(app.model, [1 1 1])
            axis(app.model, 'tight', 'off')
            material(app.model, 'dull')
            camlight(app.model, 'headlight')
            view(app.model, 3)
            
            % Set running flag to true
            app.running = true;
            
            while (app.running)
                % Update disturbances value if Apply Disturbances button is pressed
                if (app.ApplyDisturbancesButton.Value)
                    app.disturbances = [app.d_x.Value app.d_y.Value];
                else
                    app.disturbances = [0 0];
                end
               
               % Run dynamics to get new state
               [p, v, a, theta_r, theta_g, error, omega] = dynamics_gui(dt, ...
                   p0, v0, theta_g0, theta_r0, prev_error, error_i, omega_0, app.disturbances);
               
               % Set running flag to false if rocket has crashed
               if (p(3) <= 0 && t > 0)
                   app.running = false;
               end
                   
               % Display data
               app.altitude_readout.Value = num2str(p(3), '%.2f');
               app.speed.Value = num2str(norm(v), '%.2f');
               app.acceleration.Value = num2str(a(3), '%.2f');
               app.theta_x_readout.Value = num2str(theta_r0(1), '%.2f');
               app.theta_y_readout.Value = num2str(theta_r0(2), '%.2f');
               
               % Update 3D model
               rot = angle2dcm(0, -deg2rad(theta_r0(1)), -deg2rad(theta_r0(2)));
               p1.Vertices = (rot*OrigVerts')';
               
               % Update animatedline with new point
               addpoints(alt, t, p(3));
               addpoints(theta_rx, t, theta_r(1));
               addpoints(theta_gx, t, theta_g(1));
               addpoints(theta_xref, t, 0);
               addpoints(theta_ry, t, theta_r(2));
               addpoints(theta_gy, t, theta_g(2));
               addpoints(theta_yref, t, 0);
               addpoints(flightpath, p(1), p(2), p(3));
               
               % Set limits flight data plots and implement scrolling
               max_lim_x = max([30 max_lim_x abs(theta_r(1)) abs(theta_g(1))]);
               max_lim_y = max([30 max_lim_y abs(theta_r(2)) abs(theta_g(2))]);
               if (t < 1)
                   axis(app.theta_x, [0 1 -max_lim_x max_lim_x])
                   axis(app.theta_y, [0 1 -max_lim_y max_lim_y])
               else
                   axis(app.theta_x, [t-1 t -max_lim_x max_lim_x])
                   axis(app.theta_y, [t-1 t -max_lim_y max_lim_y])
               end
               
               % Set limits for x- and y-axes on flight path plot
               max_lim_path = max([max_lim_path abs(p(1)) abs(p(2))]);
               if max_lim_path
                  axis(app.flight, [-max_lim_path max_lim_path -max_lim_path max_lim_path])
               end
               drawnow limitrate
               
               % Update initial variables for next time step
               p0 = p;
               v0 = v;
               theta_r0 = theta_r;
               theta_g0 = theta_g;
               prev_error = error;
               error_i = error_i + error;
               omega_0 = omega;
               t = t + dt;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.flight = uiaxes(app.PlotsPanel);
            title(app.flight, 'Flight Path')
            xlabel(app.flight, 'x [m]')
            ylabel(app.flight, 'y [m]')
            app.theta_x = uiaxes(app.PlotsPanel);
            title(app.theta_x, 'Flight Data (x-axis)')
            xlabel(app.theta_x, 'Time [s]')
            ylabel(app.theta_x, 'Output [deg]')
            app.theta_y = uiaxes(app.PlotsPanel);
            title(app.theta_y, 'Flight Data (y-axis)')
            xlabel(app.theta_y, 'Time [s]')
            ylabel(app.theta_y, 'Output [deg]')
            app.altitude = uiaxes(app.PlotsPanel);
            title(app.altitude, 'Altitude')
            xlabel(app.altitude, 'Time [s]')
            ylabel(app.altitude, 'Altitude [m]')
            app.model = uiaxes(app.PlotsPanel);
            title(app.model, 'Model')
            app.DataReadoutsPanel = uipanel(app.UIFigure);
            app.DataReadoutsPanel.Title = 'Data Readouts';
            app.SpeedmsLabel = uilabel(app.DataReadoutsPanel);
            app.SpeedmsLabel.Text = 'Speed [m/s]';
            app.speed = uitextarea(app.DataReadoutsPanel);
            app.Accelzaxisms2Label = uilabel(app.DataReadoutsPanel);
            app.Accelzaxisms2Label.Text = 'Accel (z-axis) [m/s^2]';
            app.acceleration = uitextarea(app.DataReadoutsPanel);
            app.AltitudemLabel = uilabel(app.DataReadoutsPanel);
            app.AltitudemLabel.Text = 'Altitude [m]';
            app.altitude_readout = uitextarea(app.DataReadoutsPanel);
            app.RocketOrientationxaxisdegLabel = uilabel(app.DataReadoutsPanel);
            app.RocketOrientationxaxisdegLabel.Text = {'Rocket Orientation'; '(x-axis) [deg]'};
            app.theta_x_readout = uitextarea(app.DataReadoutsPanel);
            app.RocketOrientationyaxisdegLabel = uilabel(app.DataReadoutsPanel);
            app.RocketOrientationyaxisdegLabel.Text = {'Rocket Orientation'; '(y-axis) [deg]'};
            app.theta_y_readout = uitextarea(app.DataReadoutsPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitialStatesTab = uitab(app.TabGroup);
            app.InitialStatesTab.Title = 'Initial States';
            app.InitialRocketAnglexaxisSpinnerLabel = uilabel(app.InitialStatesTab);
            app.InitialRocketAnglexaxisSpinnerLabel.Text = {'Initial Rocket'; 'Angle (x-axis)'};
            app.theta_r0x = uispinner(app.InitialStatesTab);
            app.theta_r0x.Limits = [-45 45];
            app.InitialRocketAngleyaxisLabel = uilabel(app.InitialStatesTab);
            app.InitialRocketAngleyaxisLabel.Text = {'Initial Rocket'; 'Angle (y-axis)'};
            app.theta_r0y = uispinner(app.InitialStatesTab);
            app.theta_r0y.Limits = [-45 45];
            app.DisturbancesTab = uitab(app.TabGroup);
            app.DisturbancesTab.Title = 'Disturbances';
            app.DisturbanceForcexaxisLabel = uilabel(app.DisturbancesTab);
            app.DisturbanceForcexaxisLabel.Text = {' Disturbance'; 'Force (x-axis)'};
            app.d_x = uispinner(app.DisturbancesTab);
            app.DisturbanceForceyaxisLabel = uilabel(app.DisturbancesTab);
            app.DisturbanceForceyaxisLabel.Text = {'Disturbance'; 'Force (y-axis)'};
            app.d_y = uispinner(app.DisturbancesTab);
            app.ApplyDisturbancesButton = uibutton(app.DisturbancesTab, 'state');
            app.ApplyDisturbancesButton.Text = 'Apply Disturbances';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tvc_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end